classdef IntegradorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CantidaddeColasLabel         matlab.ui.control.Label
        CantidaddeColasEditField     matlab.ui.control.NumericEditField
        IntegradorModelodeColasMMNconXcolasLabel  matlab.ui.control.Label
        TiempoEsperaMaximoporColaEditFieldLabel  matlab.ui.control.Label
        TiempoEsperaMaximoporColaEditField  matlab.ui.control.NumericEditField
        CantidaddePersonasMaximoporColaEditFieldLabel  matlab.ui.control.Label
        CantidaddePersonasMaximoporColaEditField  matlab.ui.control.NumericEditField
        ExperimentoCheckBox          matlab.ui.control.CheckBox
        CantidaddeCorridasEditFieldLabel  matlab.ui.control.Label
        CantidaddeCorridasEditField  matlab.ui.control.NumericEditField
        SimulacionCheckBox           matlab.ui.control.CheckBox
        CantidaddeExperimentosEditFieldLabel  matlab.ui.control.Label
        CantidaddeExperimentosEditField  matlab.ui.control.NumericEditField
        IniciarButton                matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: ExperimentoCheckBox
        function ExperimentoCheckBoxValueChanged(app, event)
            value = app.ExperimentoCheckBox.Value;
            if value == true
                app.CantidaddeCorridasEditField.Enable = 'on';
            else
                app.CantidaddeCorridasEditField.Enable = 'off';
            end
        end

        % Value changed function: SimulacionCheckBox
        function SimulacionCheckBoxValueChanged(app, event)
            value = app.SimulacionCheckBox.Value;
            if value == true
                app.CantidaddeExperimentosEditField.Enable = 'on';
            else
                app.CantidaddeExperimentosEditField.Enable = 'off';
            end
        end

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
            import pkg.Integrador.*;
            colas = app.CantidaddeColasEditField.Value;
            tEspMax = app.TiempoEsperaMaximoporColaEditField.Value;
            cMax = app.CantidaddePersonasMaximoporColaEditField.Value;
            cantCorr = app.CantidaddeCorridasEditField.Value;
            cantExp = app.CantidaddeExperimentosEditField.Value;
            if not(app.ExperimentoCheckBox.Value)
                Integrador.interface(colas,0,0,tEspMax,cMax);
            else
                if not(app.SimulacionCheckBox.Value)
                    Integrador.interface(colas,cantCorr,0,tEspMax,cMax);
                else
                    Integrador.interface(colas,cantCorr,cantExp,tEspMax,cMax);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CantidaddeColasLabel = uilabel(app.UIFigure);
            app.CantidaddeColasLabel.Text = 'Cantidad de Colas:';
            app.CantidaddeColasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeColasEditField.Limits = [1 Inf];
            app.CantidaddeColasEditField.Value = 2;
            app.IntegradorModelodeColasMMNconXcolasLabel = uilabel(app.UIFigure);
            app.IntegradorModelodeColasMMNconXcolasLabel.Text = 'Integrador: Modelo de Colas M/M/N con X colas';
            app.TiempoEsperaMaximoporColaEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEsperaMaximoporColaEditFieldLabel.Text = 'Tiempo Espera Maximo por Cola:';
            app.TiempoEsperaMaximoporColaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempoEsperaMaximoporColaEditField.Limits = [1 Inf];
            app.TiempoEsperaMaximoporColaEditField.Value = 30;
            app.CantidaddePersonasMaximoporColaEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddePersonasMaximoporColaEditFieldLabel.Text = 'Cantidad de Personas Maximo por Cola:';
            app.CantidaddePersonasMaximoporColaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddePersonasMaximoporColaEditField.Limits = [1 Inf];
            app.CantidaddePersonasMaximoporColaEditField.Value = 5;
            app.ExperimentoCheckBox = uicheckbox(app.UIFigure);
            app.ExperimentoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentoCheckBoxValueChanged, true);
            app.ExperimentoCheckBox.Text = 'Experimento';
            app.CantidaddeCorridasEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeCorridasEditFieldLabel.Text = 'Cantidad de Corridas:';
            app.CantidaddeCorridasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeCorridasEditField.Limits = [1 Inf];
            app.CantidaddeCorridasEditField.Enable = 'off';
            app.CantidaddeCorridasEditField.Value = 6;
            app.SimulacionCheckBox = uicheckbox(app.UIFigure);
            app.SimulacionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulacionCheckBoxValueChanged, true);
            app.SimulacionCheckBox.Text = 'Simulacion';
            app.CantidaddeExperimentosEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeExperimentosEditFieldLabel.Text = 'Cantidad de Experimentos:';
            app.CantidaddeExperimentosEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeExperimentosEditField.Limits = [1 Inf];
            app.CantidaddeExperimentosEditField.Enable = 'off';
            app.CantidaddeExperimentosEditField.Value = 6;
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = 'Iniciar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IntegradorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end